classdef ihm_control < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        CapteurSteeringSerialGauge  matlab.ui.control.SemicircularGauge
        CapteurSteeringSerialGaugeLabel  matlab.ui.control.Label
        CommandKnob                 matlab.ui.control.DiscreteKnob
        CommandKnobLabel            matlab.ui.control.Label
        RPMGauge                    matlab.ui.control.Gauge
        RPMGaugeLabel               matlab.ui.control.Label
        CapteurSteeringGauge        matlab.ui.control.SemicircularGauge
        CapteurSteeringGaugeLabel   matlab.ui.control.Label
        ThrottleGauge               matlab.ui.control.LinearGauge
        throttleGauge_2Label_2      matlab.ui.control.Label
        DisconnectButton            matlab.ui.control.Button
        COMStatusLamp               matlab.ui.control.Lamp
        COMStatusLampLabel          matlab.ui.control.Label
        BaudRatesDropDown           matlab.ui.control.DropDown
        BaudRatesDropDownLabel      matlab.ui.control.Label
        COMPortsDropDown            matlab.ui.control.DropDown
        COMPortsDropDownLabel       matlab.ui.control.Label
        ExitRemoteButton            matlab.ui.control.Button
        ConnectButton               matlab.ui.control.Button
        ReadSerialButton            matlab.ui.control.Button
        SendSerialButton            matlab.ui.control.Button
        ThrottleSlider              matlab.ui.control.Slider
        ThrottleSliderLabel         matlab.ui.control.Label
        SteeringSlider              matlab.ui.control.Slider
        SteeringSliderLabel         matlab.ui.control.Label
    end

    properties (Access = private)
        Serial % Description
        COMPort %port COM
        BaudRate % Baude Rate
    end

    methods (Access = public)

        function updateGUI(app, varargin)
            if app.CommandKnob.Value == "Xbox"
                try
                    Throttle_Xbox = get_param([bdroot,'/Throttle_Xbox'],'RuntimeObject');
                    Steering_Xbox = get_param([bdroot,'/Steering_Xbox'],'RuntimeObject');
                    app.ThrottleGauge.Value = Throttle_Xbox.InputPort(1).Data*1023;
                    app.CapteurSteeringGauge.Value = Steering_Xbox.InputPort(1).Data*30;
                    app.CapteurSteeringSerialGauge.Value = interp1([-1,1],[20,65],Steering_Xbox.InputPort(1).Data);
                    app.Serial.writeline("W" + app.ThrottleGauge.Value + ',' + interp1([-1,1],[20,65],Steering_Xbox.InputPort(1).Data))
                catch
                end
            elseif app.CommandKnob.Value == "Yolov5"
                try
                    Throttle_IA = get_param([bdroot,'/Throttle_IA'],'RuntimeObject');
                    Steering_IA = get_param([bdroot,'/Steering_IA'],'RuntimeObject');
                    app.ThrottleGauge.Value = Throttle_IA.InputPort(1).Data;
                    app.CapteurSteeringGauge.Value = Steering_IA.InputPort(1).Data;
                    app.CapteurSteeringSerialGauge.Value = Steering_IA.InputPort(1).Data;
                    app.Serial.writeline("W" + app.ThrottleGauge.Value + ',' + Steering_IA.InputPort(1).Data);
                catch
                end
            elseif app.CommandKnob.Value == "G920"
                try
                    Throttle_G920 = get_param([bdroot,'/Throttle_G920'],'RuntimeObject');
                    Steering_G920 = get_param([bdroot,'/Steering_G920'],'RuntimeObject');
                    ButtonA_G920 = get_param([bdroot,'/ButtonA_G920'],'RuntimeObject');
                    klaxon = 0;
                    if ButtonA_G920.InputPort(1).Data
                        klaxon  = 1;
                    end
                    app.ThrottleGauge.Value = interp1([1,-1],[0,1023],Throttle_G920.InputPort(1).Data);
                    app.CapteurSteeringGauge.Value = Steering_G920.InputPort(1).Data*30;
                    app.CapteurSteeringSerialGauge.Value = interp1([-1,1],[20,65],Steering_G920.InputPort(1).Data);
                    app.Serial.writeline("W" + app.ThrottleGauge.Value + ',' + interp1([-1,1],[20,65],Steering_G920.InputPort(1).Data) + ',' + klaxon);
                catch
                end
            end
            pause(0.02);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ReadSerialButton
        function ReadSerialButtonPushed(app, event)
            try
                app.Serial.writeline("R");
                response = char(app.Serial.readline());
                app.ThrottleGauge.Value = str2double(response(2:find(response==',')-1));
                app.CapteurSteeringSerialGauge.Value = str2double(response(find(response==',')+1:length(response)));
            catch ME
                msgbox(ME.identifier);
            end
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            try
                app.Serial = serialport(app.COMPort, str2double(app.BaudRatesDropDown.Value));
                pause(3);
                if app.Serial.Status == "open"
                    msgbox("Sucessfully Connected to port " + char(app.COMPort) + "!");
                    app.COMStatusLamp.Color = [0.00, 1.00, 0.00];
                else
                    msgbox("Can't Connect to port " + char(app.COMPort) + "!");
                    app.COMStatusLamp.Color = [1.00, 0.00, 0.00];
                end
            catch
                msgbox("Check your Serial Port !");
            end
        end

        % Button pushed function: SendSerialButton
        function SendSerialButtonPushed(app, event)
            try
                app.Serial.writeline("W" + app.ThrottleSlider.Value + ',' + app.SteeringSlider.Value);
                response = app.Serial.readline();
                if response == 'F'
                    msgbox("Write failed: invalid parameter");
                else
                    msgbox("Write Success!");
                end
            catch ME
                msgbox(ME.identifier);
            end
        end

        % Button pushed function: ExitRemoteButton
        function ExitRemoteButtonPushed(app, event)
            app.Serial.writeline("X")
            response = char(app.Serial.readline());
            if response == "R"
                msgbox("Remote control exited !");
            else
                msgbox("Error quitting remote");
            end
        end

        % Clicked callback: COMPortsDropDown
        function COMPortsDropDownClicked(app, event)
            app.COMPortsDropDown.Items = serialportlist();
            app.COMPort = app.COMPortsDropDown.Value;
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            try
                app.Serial.delete
                msgbox("Sucessfully Disconnected to port " + char(app.COMPort) + "!");
                app.COMStatusLamp.Color = [1.00, 0.00, 0.00];
            catch
                msgbox("Not Connected to a Serial Port");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SteeringSliderLabel = uilabel(app.UIFigure);
            app.SteeringSliderLabel.Text = 'Steering';
            app.SteeringSlider = uislider(app.UIFigure);
            app.SteeringSlider.Limits = [20 65];
            app.SteeringSlider.Value = 42.5;
            app.ThrottleSliderLabel = uilabel(app.UIFigure);
            app.ThrottleSliderLabel.Text = 'Throttle';
            app.ThrottleSlider = uislider(app.UIFigure);
            app.ThrottleSlider.Limits = [0 1023];
            app.SendSerialButton = uibutton(app.UIFigure, 'push');
            app.SendSerialButton.ButtonPushedFcn = createCallbackFcn(app, @SendSerialButtonPushed, true);
            app.SendSerialButton.Text = 'Send Serial';
            app.ReadSerialButton = uibutton(app.UIFigure, 'push');
            app.ReadSerialButton.ButtonPushedFcn = createCallbackFcn(app, @ReadSerialButtonPushed, true);
            app.ReadSerialButton.Text = 'Read Serial';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.ExitRemoteButton = uibutton(app.UIFigure, 'push');
            app.ExitRemoteButton.ButtonPushedFcn = createCallbackFcn(app, @ExitRemoteButtonPushed, true);
            app.ExitRemoteButton.Text = 'Exit Remote';
            app.COMPortsDropDownLabel = uilabel(app.UIFigure);
            app.COMPortsDropDownLabel.Text = 'COM Ports';
            app.COMPortsDropDown = uidropdown(app.UIFigure);
            app.COMPortsDropDown.Items = {};
            app.COMPortsDropDown.ClickedFcn = createCallbackFcn(app, @COMPortsDropDownClicked, true);
            app.COMPortsDropDown.Value = {};
            app.BaudRatesDropDownLabel = uilabel(app.UIFigure);
            app.BaudRatesDropDownLabel.Text = 'Baud Rates';
            app.BaudRatesDropDown = uidropdown(app.UIFigure);
            app.BaudRatesDropDown.Items = {'110', '300', '600', '1200', '2400', '4800', '9600', '14400', '19200', '38400', '57600', '115200', '128000', '256000'};
            app.BaudRatesDropDown.Value = '115200';
            app.COMStatusLampLabel = uilabel(app.UIFigure);
            app.COMStatusLampLabel.Text = 'COM Status';
            app.COMStatusLamp = uilamp(app.UIFigure);
            app.DisconnectButton = uibutton(app.UIFigure, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.throttleGauge_2Label_2 = uilabel(app.UIFigure);
            app.throttleGauge_2Label_2.Text = 'Throttle Gauge';
            app.ThrottleGauge = uigauge(app.UIFigure, 'linear');
            app.ThrottleGauge.Limits = [0 1023];
            app.CapteurSteeringGaugeLabel = uilabel(app.UIFigure);
            app.CapteurSteeringGaugeLabel.Text = 'Capteur Steering';
            app.CapteurSteeringGauge = uigauge(app.UIFigure, 'semicircular');
            app.CapteurSteeringGauge.Limits = [-30 30];
            app.RPMGaugeLabel = uilabel(app.UIFigure);
            app.RPMGaugeLabel.Text = 'RPM';
            app.RPMGauge = uigauge(app.UIFigure, 'circular');
            app.CommandKnobLabel = uilabel(app.UIFigure);
            app.CommandKnobLabel.Text = 'Command Knob';
            app.CommandKnob = uiknob(app.UIFigure, 'discrete');
            app.CommandKnob.Items = {'Consigne', 'Xbox', 'Yolov5', 'G920'};
            app.CommandKnob.Value = 'Consigne';
            app.CapteurSteeringSerialGaugeLabel = uilabel(app.UIFigure);
            app.CapteurSteeringSerialGaugeLabel.Text = 'Capteur Steering Serial';
            app.CapteurSteeringSerialGauge = uigauge(app.UIFigure, 'semicircular');
            app.CapteurSteeringSerialGauge.Limits = [20 65];
            app.CapteurSteeringSerialGauge.Value = 42.5;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ihm_control

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end